classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EnterValuesButton         matlab.ui.control.Button
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        SendHomeButton            matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        ForwardKinematicsTab      matlab.ui.container.Tab
        Z_valueLabel              matlab.ui.control.Label
        Y_valueLabel              matlab.ui.control.Label
        X_valueLabel              matlab.ui.control.Label
        PositionZLabel            matlab.ui.control.Label
        PositionYLabel            matlab.ui.control.Label
        PositionXLabel            matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        ForwardKinematicsLabel    matlab.ui.control.Label
        InverseKinematicsTab      matlab.ui.container.Tab
        MoveButton_4              matlab.ui.control.Button
        MoveButton_3              matlab.ui.control.Button
        MoveButton_2              matlab.ui.control.Button
        MoveButton                matlab.ui.control.Button
        Lamp_4                    matlab.ui.control.Lamp
        Lamp_3                    matlab.ui.control.Lamp
        Lamp_2                    matlab.ui.control.Lamp
        Lamp                      matlab.ui.control.Lamp
        Solution4Label            matlab.ui.control.Label
        Solution3Label            matlab.ui.control.Label
        Solution2Label            matlab.ui.control.Label
        Solution1Label            matlab.ui.control.Label
        Sol4Label                 matlab.ui.control.Label
        Sol3Label                 matlab.ui.control.Label
        Sol2Label                 matlab.ui.control.Label
        Sol1Label                 matlab.ui.control.Label
        SolutionsLabel            matlab.ui.control.Label
        mmLabel_3                 matlab.ui.control.Label
        mmLabel_2                 matlab.ui.control.Label
        mmLabel                   matlab.ui.control.Label
        DesiredPositionLabel      matlab.ui.control.Label
        InverseKinematicsLabel    matlab.ui.control.Label
        EnterValuesButton_2       matlab.ui.control.Button
        ZEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        TrajectoryPlanningTab     matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        LinearMoveTab             matlab.ui.container.Tab
        DesiredEndPositionLabel   matlab.ui.control.Label
        Z_finalEditField          matlab.ui.control.NumericEditField
        Z_finalEditFieldLabel     matlab.ui.control.Label
        Y_finalEditField          matlab.ui.control.NumericEditField
        Y_finalEditFieldLabel     matlab.ui.control.Label
        X_finalEditField          matlab.ui.control.NumericEditField
        X_finalEditFieldLabel     matlab.ui.control.Label
        EnterValuesButton_3       matlab.ui.control.Button
        CircularMoveTab           matlab.ui.container.Tab
        EnterValuesButton_4       matlab.ui.control.Button
        Y_FinalEditField          matlab.ui.control.NumericEditField
        Y_FinalEditFieldLabel     matlab.ui.control.Label
        X_FinalEditField          matlab.ui.control.NumericEditField
        X_FinalEditFieldLabel     matlab.ui.control.Label
        Z_genEditField            matlab.ui.control.NumericEditField
        Z_genEditFieldLabel       matlab.ui.control.Label
        Y_InterEditField          matlab.ui.control.NumericEditField
        Y_InterEditFieldLabel     matlab.ui.control.Label
        X_InterEditField          matlab.ui.control.NumericEditField
        X_InterEditFieldLabel     matlab.ui.control.Label
        TrajectoryPlanningLabel   matlab.ui.control.Label
        TeachingModeTab           matlab.ui.container.Tab
        ResetButton               matlab.ui.control.Button
        PlayButton                matlab.ui.control.Button
        TeachPositionButton       matlab.ui.control.Button
        TeachingModeLabel         matlab.ui.control.Label
        ArduinoConnectionLabel    matlab.ui.control.Label
        SerialPortEditField       matlab.ui.control.EditField
        SerialPortEditFieldLabel  matlab.ui.control.Label
        q3_valueLabel             matlab.ui.control.Label
        q2_valueLabel             matlab.ui.control.Label
        q1_valueLabel             matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        ManualModeLabel           matlab.ui.control.Label
        Q3Knob                    matlab.ui.control.Knob
        Q3KnobLabel               matlab.ui.control.Label
        Q2Knob                    matlab.ui.control.Knob
        Q2KnobLabel               matlab.ui.control.Label
        Q1Knob                    matlab.ui.control.Knob
        Q1KnobLabel               matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Global Variables
        device 
        theta1_dyn = 512; 
        theta2_dyn = 512;
        theta3_dyn = 512;
        COM = "COM4"
        theta1_deg = 150;
        theta2_deg = 150;
        theta3_deg = 150;
        P4X = 0;
        P4Y = 0;
        P4Z = 0;
        qs;
        InputQ1 = 0;
        InputQ2 = 0;
        InputQ3 = 0;
        x_final = 0;
        y_final = 0;
        z_final = 0;
        x_actual = 0;
        y_actual = 0;
        z_actual = 0;
        qs_good;
        qs_bad;
        x_inter = 0;
        y_inter = 0;
        z_inter = 0;
        x_final2 = 0;
        y_final2 = 0;
        z_final2 = 0;
        teach_t1 = []
        teach_t2 = []
        teach_t3 = []
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Start the serial communication 
            disp("Starting Serial Communication") 
            app.device = serialport(app.COM, 9600); 

            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Starting Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 
        end

        % Value changing function: Q1Knob
        function Q1KnobValueChanging(app, event)
            app.theta1_deg = event.Value + 150;
            app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
            app.theta1_dyn = app.theta1_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
               
            % Send q values to Arduino
            send_q_values(app.device, theta_array);
            pause(1.0)
        end

        % Value changing function: Q2Knob
        function Q2KnobValueChanging(app, event)
            app.theta2_deg = event.Value + 240;
            app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
            app.theta2_dyn = app.theta2_deg * 1023 / 300;
            
            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 

            % Send q values to Arduino
            send_q_values(app.device, theta_array);
            pause(1.0)
        end

        % Value changing function: Q3Knob
        function Q3KnobValueChanging(app, event)
            app.theta3_deg = event.Value + 150;
            app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
            app.theta3_dyn = app.theta3_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
            
            % Send q values to Arduino
            send_q_values(app.device, theta_array);
            pause(1.0)
        end

        % Value changed function: Q1Knob
        function Q1KnobValueChanged(app, event)
            app.theta1_deg = app.Q1Knob.Value + 150;
            app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
            app.theta1_dyn = app.theta1_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
            
            % Send q values to Arduino
            send_q_values(app.device, theta_array);
            pause(1.0)
        end

        % Value changed function: Q2Knob
        function Q2KnobValueChanged(app, event)
            app.theta2_deg = app.Q2Knob.Value + 240;
            app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
            app.theta2_dyn = app.theta2_deg * 1023 / 300;
            
            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 

            % Send q values to Arduino
            send_q_values(app.device, theta_array);
            pause(1.0)
        end

        % Value changed function: Q3Knob
        function Q3KnobValueChanged(app, event)
            app.theta3_deg = app.Q3Knob.Value + 150;
            app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
            app.theta3_dyn = app.theta3_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 

            % Send q values to Arduino
            send_q_values(app.device, theta_array);
            pause(1.0)
        end

        % Value changed function: SerialPortEditField
        function SerialPortEditFieldValueChanged(app, event)
            app.COM = app.SerialPortEditField.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Get DH parameters
            [DH, q] = DH_parameters(app.theta1_deg - 150, app.theta2_deg - 240, app.theta3_deg - 150);
            
            % Direct Geometric Model
            [Tn, T0Tn, X, Y, Z] = DGM(DH, q);
    
            plot3(app.UIAxes, X, Y, Z, '-ro', 'linewidth', 3);

            app.x_actual = T0Tn{end}(1,4);
            app.y_actual = T0Tn{end}(2,4);
            app.z_actual = T0Tn{end}(3,4);

            % Shows end effector position
            app.X_valueLabel.Text = strcat(num2str(round(app.x_actual)), " mm");
            app.Y_valueLabel.Text = strcat(num2str(round(app.y_actual, 2)), " mm");
            app.Z_valueLabel.Text = strcat(num2str(round(app.z_actual, 2)), " mm");
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.P4X = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.P4Y = app.YEditField.Value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.P4Z = app.ZEditField.Value;
        end

        % Button pushed function: EnterValuesButton_2
        function EnterValuesButton_2Pushed(app, event)
            app.qs = IGM(app.P4X, app.P4Y, app.P4Z);

            qs_deg = app.qs;

            app.Solution1Label.Text = strcat("q1 = ", num2str(round(qs_deg(1, 1), 2)), ", ", "q2 = ", num2str(round(qs_deg(1, 2), 2)), ", ", "q3 = ", num2str(round(qs_deg(1, 3), 2)));
            app.Solution2Label.Text = strcat("q1 = ", num2str(round(qs_deg(2, 1), 2)), ", ", "q2 = ", num2str(round(qs_deg(2, 2), 2)), ", ", "q3 = ", num2str(round(qs_deg(2, 3), 2)));
            app.Solution3Label.Text = strcat("q1 = ", num2str(round(qs_deg(3, 1), 2)), ", ", "q2 = ", num2str(round(qs_deg(3, 2), 2)), ", ", "q3 = ", num2str(round(qs_deg(3, 3), 2)));
            app.Solution4Label.Text = strcat("q1 = ", num2str(round(qs_deg(4, 1), 2)), ", ", "q2 = ", num2str(round(qs_deg(4, 2), 2)), ", ", "q3 = ", num2str(round(qs_deg(4, 3), 2)));

            % Determine reachable qs
            [app.qs_good, app.qs_bad] = possible_solutions(qs_deg);

            % Determine best qs
            qs_best = best_solution(app.qs_good, app.theta1_deg, app.theta2_deg, app.theta3_deg);

            if qs_best(1) ~= 300
                % Move the robot with the best configuration of qs
                app.theta1_deg = qs_best(1) + 150;
                app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
                app.theta1_dyn = app.theta1_deg * 1023 / 300;
                app.theta2_deg = qs_best(2) + 240;
                app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
                app.theta2_dyn = app.theta2_deg * 1023 / 300;
                app.theta3_deg = qs_best(3) + 150;
                app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
                app.theta3_dyn = app.theta3_deg * 1023 / 300;
    
                % Write the string of data to the Arduino 
                theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
    
                % Send q values to Arduino
                send_q_values(app.device, theta_array); 
            end
            % Lamps indicator for possible qs
            if app.qs_bad(1,1) == 300
                app.Lamp.Color = 'green';
            else 
                app.Lamp.Color = 'red';
            end
            if app.qs_bad(2,1) == 300
                app.Lamp_2.Color = 'green';
            else 
                app.Lamp_2.Color = 'red';
            end
            if app.qs_bad(3,1) == 300
                app.Lamp_3.Color = 'green';
            else 
                app.Lamp_3.Color = 'red';
            end
            if app.qs_bad(4,1) == 300
                app.Lamp_4.Color = 'green';
            else 
                app.Lamp_4.Color = 'red';
            end
        end

        % Button pushed function: SendHomeButton
        function SendHomeButtonPushed(app, event)
            app.theta1_deg = 0 + 150;
            app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
            app.theta1_dyn = app.theta1_deg * 1023 / 300;
            app.theta2_deg = -90 + 240;
            app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
            app.theta2_dyn = app.theta2_deg * 1023 / 300;
            app.theta3_deg = 0 + 150;
            app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
            app.theta3_dyn = app.theta3_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");

            % Send q values to Arduino
            send_q_values(app.device, theta_array);  
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.InputQ1 = app.EditField.Value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.InputQ2 = app.EditField_2.Value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.InputQ3 = app.EditField_3.Value;
        end

        % Button pushed function: EnterValuesButton
        function EnterValuesButtonPushed(app, event)
            app.theta1_deg = app.InputQ1 + 150;
            app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
            app.theta1_dyn = app.theta1_deg * 1023 / 300;
            app.theta2_deg = app.InputQ2 + 240;
            app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
            app.theta2_dyn = app.theta2_deg * 1023 / 300;
            app.theta3_deg = app.InputQ3 + 150;
            app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
            app.theta3_dyn = app.theta3_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");

            % Send q values to Arduino
            send_q_values(app.device, theta_array);  
        end

        % Value changed function: X_finalEditField
        function X_finalEditFieldValueChanged(app, event)
            app.x_final = app.X_finalEditField.Value;
        end

        % Value changed function: Y_finalEditField
        function Y_finalEditFieldValueChanged(app, event)
            app.y_final = app.Y_finalEditField.Value;
        end

        % Value changed function: Z_finalEditField
        function Z_finalEditFieldValueChanged(app, event)
            app.z_final = app.Z_finalEditField.Value;   
        end

        % Button pushed function: EnterValuesButton_3
        function EnterValuesButton_3Pushed(app, event)
            % Get DH parameters
            [DH, q] = DH_parameters(app.theta1_deg - 150, app.theta2_deg - 240, app.theta3_deg - 150);
            
            % Direct Geometric Model
            [Tn, T0Tn, X, Y, Z] = DGM(DH, q);
    
            app.x_actual = T0Tn{end}(1,4);
            app.y_actual = T0Tn{end}(2,4);
            app.z_actual = T0Tn{end}(3,4);
            
            points = pointsIGM(app.x_actual, app.y_actual, app.z_actual, app.x_final, app.y_final, app.z_final);

            for i = 2: length(points)
                qs_deg = IGM(points(i,1), points(i,2), points(i,3));
                % Determine reachable qs
                app.qs_good = possible_solutions(qs_deg);

                % Determine best qs
                qs_best = best_solution(app.qs_good, app.theta1_deg, app.theta2_deg, app.theta3_deg);

                % Move the robot with the best configuration of qs
                app.theta1_deg = qs_best(1) + 150;
                app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
                app.theta1_dyn = app.theta1_deg * 1023 / 300;
                app.theta2_deg = qs_best(2) + 240;
                app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
                app.theta2_dyn = app.theta2_deg * 1023 / 300;
                app.theta3_deg = qs_best(3) + 150;
                app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
                app.theta3_dyn = app.theta3_deg * 1023 / 300;
    
                % Write the string of data to the Arduino 
                theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
    
                % Send q values to Arduino
                send_q_values(app.device, theta_array); 
                pause(0.1)
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            if app.qs_good(1, 1) == 300
                fig = uifigure;
                selection = uiconfirm(fig,'Not Reachable','Warning','Icon','warning');
            else
                % Move the robot with the best configuration of qs
                app.theta1_deg = app.qs_good(1,1) + 150;
                app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
                app.theta1_dyn = app.theta1_deg * 1023 / 300;
                app.theta2_deg = app.qs_good(1,2) + 240;
                app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
                app.theta2_dyn = app.theta2_deg * 1023 / 300;
                app.theta3_deg = app.qs_good(1,3) + 150;
                app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
                app.theta3_dyn = app.theta3_deg * 1023 / 300;

                % Write the string of data to the Arduino 
                theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
    
                % Send q values to Arduino
                send_q_values(app.device, theta_array);
            end
        end

        % Button pushed function: MoveButton_2
        function MoveButton_2Pushed(app, event)
            if app.qs_good(2, 1) == 300
                fig = uifigure;
                selection = uiconfirm(fig,'Not Reachable','Warning','Icon','warning');
            else
                % Move the robot with the best configuration of qs
                app.theta1_deg = app.qs_good(2,1) + 150;
                app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
                app.theta1_dyn = app.theta1_deg * 1023 / 300;
                app.theta2_deg = app.qs_good(2,2) + 240;
                app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
                app.theta2_dyn = app.theta2_deg * 1023 / 300;
                app.theta3_deg = app.qs_good(2,3) + 150;
                app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
                app.theta3_dyn = app.theta3_deg * 1023 / 300;

                % Write the string of data to the Arduino 
                theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
    
                % Send q values to Arduino
                send_q_values(app.device, theta_array);
            end
        end

        % Button pushed function: MoveButton_3
        function MoveButton_3Pushed(app, event)
            if app.qs_good(3, 1) == 300
                fig = uifigure;
                selection = uiconfirm(fig,'Not Reachable','Warning','Icon','warning');
            else
                % Move the robot with the best configuration of qs
                app.theta1_deg = app.qs_good(3,1) + 150;
                app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
                app.theta1_dyn = app.theta1_deg * 1023 / 300;
                app.theta2_deg = app.qs_good(3,2) + 240;
                app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
                app.theta2_dyn = app.theta2_deg * 1023 / 300;
                app.theta3_deg = app.qs_good(3,3) + 150;
                app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
                app.theta3_dyn = app.theta3_deg * 1023 / 300;

                % Write the string of data to the Arduino 
                theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
    
                % Send q values to Arduino
                send_q_values(app.device, theta_array);
            end
        end

        % Button pushed function: MoveButton_4
        function MoveButton_4Pushed(app, event)
            if app.qs_good(4, 1) == 300
                fig = uifigure;
                selection = uiconfirm(fig,'Not Reachable','Warning','Icon','warning');
            else
                % Move the robot with the best configuration of qs
                app.theta1_deg = app.qs_good(4,1) + 150;
                app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
                app.theta1_dyn = app.theta1_deg * 1023 / 300;
                app.theta2_deg = app.qs_good(4,2) + 240;
                app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
                app.theta2_dyn = app.theta2_deg * 1023 / 300;
                app.theta3_deg = app.qs_good(4,3) + 150;
                app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
                app.theta3_dyn = app.theta3_deg * 1023 / 300;

                % Write the string of data to the Arduino 
                theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
    
                % Send q values to Arduino
                send_q_values(app.device, theta_array);
            end
        end

        % Button pushed function: EnterValuesButton_4
        function EnterValuesButton_4Pushed(app, event)
            % Get DH parameters
            [DH, q] = DH_parameters(app.theta1_deg - 150, app.theta2_deg - 240, app.theta3_deg - 150);
            
            % Direct Geometric Model
            [Tn, T0Tn, X, Y, Z] = DGM(DH, q);
    
            app.x_actual = T0Tn{end}(1,4);
            app.y_actual = T0Tn{end}(2,4);
            app.z_actual = T0Tn{end}(3,4);

            circle_points = circlepointsIGM(app.x_actual, app.y_actual, app.x_inter, app.y_inter, app.x_final2, app.y_final2, app.z_actual);
            
            for i = 2: length(circle_points)
                qs_deg = IGM(circle_points(i,1), circle_points(i,2), circle_points(i,3));
 
                % Determine reachable qs
                app.qs_good = possible_solutions(qs_deg);

                % Determine best qs
                qs_best = best_solution(app.qs_good, app.theta1_deg, app.theta2_deg, app.theta3_deg);

                % Move the robot with the best configuration of qs
                app.theta1_deg = qs_best(1) + 150;
                app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
                app.theta1_dyn = app.theta1_deg * 1023 / 300;
                app.theta2_deg = qs_best(2) + 240;
                app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
                app.theta2_dyn = app.theta2_deg * 1023 / 300;
                app.theta3_deg = qs_best(3) + 150;
                app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
                app.theta3_dyn = app.theta3_deg * 1023 / 300;
    
                % Write the string of data to the Arduino 
                theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
    
                % Send q values to Arduino
                send_q_values(app.device, theta_array); 
                pause(0.1)
            end

        end

        % Value changed function: X_InterEditField
        function X_InterEditFieldValueChanged(app, event)
            app.x_inter = app.X_InterEditField.Value;
        end

        % Value changed function: Y_InterEditField
        function Y_InterEditFieldValueChanged(app, event)
            app.y_inter = app.Y_InterEditField.Value;
        end

        % Value changed function: Z_genEditField
        function Z_genEditFieldValueChanged(app, event)
            app.z_gen = app.Z_genEditField.Value;
        end

        % Value changed function: X_FinalEditField
        function X_FinalEditFieldValueChanged(app, event)
            app.x_final2 = app.X_FinalEditField.Value;
        end

        % Value changed function: Y_FinalEditField
        function Y_FinalEditFieldValueChanged(app, event)
            app.y_final2 = app.Y_FinalEditField.Value;
        end

        % Button pushed function: TeachPositionButton
        function TeachPositionButtonPushed(app, event)
            % Save theta values
            app.teach_t1(end+1) = app.theta1_deg;
            app.teach_t2(end+1) = app.theta2_deg;
            app.teach_t3(end+1) = app.theta3_deg;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            for i = 1: length(app.teach_t1)
                % Move the robot with the best configuration of qs
                app.theta1_deg = app.teach_t1(i);
                app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
                app.theta1_dyn = app.theta1_deg * 1023 / 300;
                app.theta2_deg = app.teach_t2(i);
                app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
                app.theta2_dyn = app.theta2_deg * 1023 / 300;
                app.theta3_deg = app.teach_t3(i);
                app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
                app.theta3_dyn = app.theta3_deg * 1023 / 300;
    
                % Write the string of data to the Arduino 
                theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
    
                % Send q values to Arduino
                send_q_values(app.device, theta_array); 
                pause(2.0)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.teach_t1 = [];
            app.teach_t2 = [];
            app.teach_t3 = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Configuration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-300 300];
            app.UIAxes.YLim = [-300 300];
            app.UIAxes.ZLim = [0 330];
            app.Q1KnobLabel = uilabel(app.UIFigure);
            app.Q1KnobLabel.Text = 'Q1';
            app.Q1Knob = uiknob(app.UIFigure, 'continuous');
            app.Q1Knob.Limits = [-150 150];
            app.Q1Knob.ValueChangedFcn = createCallbackFcn(app, @Q1KnobValueChanged, true);
            app.Q1Knob.ValueChangingFcn = createCallbackFcn(app, @Q1KnobValueChanging, true);
            app.Q2KnobLabel = uilabel(app.UIFigure);
            app.Q2KnobLabel.Text = 'Q2';
            app.Q2Knob = uiknob(app.UIFigure, 'continuous');
            app.Q2Knob.Limits = [-240 60];
            app.Q2Knob.ValueChangedFcn = createCallbackFcn(app, @Q2KnobValueChanged, true);
            app.Q2Knob.ValueChangingFcn = createCallbackFcn(app, @Q2KnobValueChanging, true);
            app.Q3KnobLabel = uilabel(app.UIFigure);
            app.Q3KnobLabel.Text = 'Q3';
            app.Q3Knob = uiknob(app.UIFigure, 'continuous');
            app.Q3Knob.Limits = [-150 150];
            app.Q3Knob.ValueChangedFcn = createCallbackFcn(app, @Q3KnobValueChanged, true);
            app.Q3Knob.ValueChangingFcn = createCallbackFcn(app, @Q3KnobValueChanging, true);
            app.ManualModeLabel = uilabel(app.UIFigure);
            app.ManualModeLabel.Text = 'Manual Mode';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.q1_valueLabel = uilabel(app.UIFigure);
            app.q1_valueLabel.Text = 'q1_value';
            app.q2_valueLabel = uilabel(app.UIFigure);
            app.q2_valueLabel.Text = 'q2_value';
            app.q3_valueLabel = uilabel(app.UIFigure);
            app.q3_valueLabel.Text = 'q3_value';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.ValueChangedFcn = createCallbackFcn(app, @SerialPortEditFieldValueChanged, true);
            app.ArduinoConnectionLabel = uilabel(app.UIFigure);
            app.ArduinoConnectionLabel.Text = 'Arduino Connection';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ForwardKinematicsTab = uitab(app.TabGroup);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.ForwardKinematicsLabel = uilabel(app.ForwardKinematicsTab);
            app.ForwardKinematicsLabel.Text = {'Forward Kinematics'; ''};
            app.CalculateButton = uibutton(app.ForwardKinematicsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PositionXLabel = uilabel(app.ForwardKinematicsTab);
            app.PositionXLabel.Text = 'Position X:';
            app.PositionYLabel = uilabel(app.ForwardKinematicsTab);
            app.PositionYLabel.Text = 'Position Y:';
            app.PositionZLabel = uilabel(app.ForwardKinematicsTab);
            app.PositionZLabel.Text = 'Position Z:';
            app.X_valueLabel = uilabel(app.ForwardKinematicsTab);
            app.X_valueLabel.Text = 'X_value';
            app.Y_valueLabel = uilabel(app.ForwardKinematicsTab);
            app.Y_valueLabel.Text = 'Y_value';
            app.Z_valueLabel = uilabel(app.ForwardKinematicsTab);
            app.Z_valueLabel.Text = 'Z_value';
            app.InverseKinematicsTab = uitab(app.TabGroup);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.XEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.XEditField.ValueDisplayFormat = '%11.5g';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.YEditField.ValueDisplayFormat = '%11.5g';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%11.5g';
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.EnterValuesButton_2 = uibutton(app.InverseKinematicsTab, 'push');
            app.EnterValuesButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnterValuesButton_2Pushed, true);
            app.EnterValuesButton_2.Text = 'Enter Values';
            app.InverseKinematicsLabel = uilabel(app.InverseKinematicsTab);
            app.InverseKinematicsLabel.Text = {'Inverse Kinematics'; ''};
            app.DesiredPositionLabel = uilabel(app.InverseKinematicsTab);
            app.DesiredPositionLabel.Text = 'Desired Position';
            app.mmLabel = uilabel(app.InverseKinematicsTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.InverseKinematicsTab);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.InverseKinematicsTab);
            app.mmLabel_3.Text = 'mm';
            app.SolutionsLabel = uilabel(app.InverseKinematicsTab);
            app.SolutionsLabel.Text = 'Solutions';
            app.Sol1Label = uilabel(app.InverseKinematicsTab);
            app.Sol1Label.Text = 'Sol. 1:';
            app.Sol2Label = uilabel(app.InverseKinematicsTab);
            app.Sol2Label.Text = 'Sol. 2:';
            app.Sol3Label = uilabel(app.InverseKinematicsTab);
            app.Sol3Label.Text = 'Sol. 3:';
            app.Sol4Label = uilabel(app.InverseKinematicsTab);
            app.Sol4Label.Text = 'Sol. 4:';
            app.Solution1Label = uilabel(app.InverseKinematicsTab);
            app.Solution1Label.Text = 'Solution 1';
            app.Solution2Label = uilabel(app.InverseKinematicsTab);
            app.Solution2Label.Text = 'Solution 2';
            app.Solution3Label = uilabel(app.InverseKinematicsTab);
            app.Solution3Label.Text = 'Solution 3';
            app.Solution4Label = uilabel(app.InverseKinematicsTab);
            app.Solution4Label.Text = 'Solution 4';
            app.Lamp = uilamp(app.InverseKinematicsTab);
            app.Lamp_2 = uilamp(app.InverseKinematicsTab);
            app.Lamp_3 = uilamp(app.InverseKinematicsTab);
            app.Lamp_4 = uilamp(app.InverseKinematicsTab);
            app.MoveButton = uibutton(app.InverseKinematicsTab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.MoveButton_2 = uibutton(app.InverseKinematicsTab, 'push');
            app.MoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_2Pushed, true);
            app.MoveButton_2.Text = 'Move';
            app.MoveButton_3 = uibutton(app.InverseKinematicsTab, 'push');
            app.MoveButton_3.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_3Pushed, true);
            app.MoveButton_3.Text = 'Move';
            app.MoveButton_4 = uibutton(app.InverseKinematicsTab, 'push');
            app.MoveButton_4.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_4Pushed, true);
            app.MoveButton_4.Text = 'Move';
            app.TrajectoryPlanningTab = uitab(app.TabGroup);
            app.TrajectoryPlanningTab.Title = 'Trajectory Planning';
            app.TrajectoryPlanningLabel = uilabel(app.TrajectoryPlanningTab);
            app.TrajectoryPlanningLabel.Text = 'Trajectory Planning';
            app.TabGroup2 = uitabgroup(app.TrajectoryPlanningTab);
            app.LinearMoveTab = uitab(app.TabGroup2);
            app.LinearMoveTab.Title = 'Linear Move';
            app.EnterValuesButton_3 = uibutton(app.LinearMoveTab, 'push');
            app.EnterValuesButton_3.ButtonPushedFcn = createCallbackFcn(app, @EnterValuesButton_3Pushed, true);
            app.EnterValuesButton_3.Text = 'Enter Values';
            app.X_finalEditFieldLabel = uilabel(app.LinearMoveTab);
            app.X_finalEditFieldLabel.Text = 'X_final';
            app.X_finalEditField = uieditfield(app.LinearMoveTab, 'numeric');
            app.X_finalEditField.ValueChangedFcn = createCallbackFcn(app, @X_finalEditFieldValueChanged, true);
            app.Y_finalEditFieldLabel = uilabel(app.LinearMoveTab);
            app.Y_finalEditFieldLabel.Text = 'Y_final';
            app.Y_finalEditField = uieditfield(app.LinearMoveTab, 'numeric');
            app.Y_finalEditField.ValueChangedFcn = createCallbackFcn(app, @Y_finalEditFieldValueChanged, true);
            app.Z_finalEditFieldLabel = uilabel(app.LinearMoveTab);
            app.Z_finalEditFieldLabel.Text = 'Z_final';
            app.Z_finalEditField = uieditfield(app.LinearMoveTab, 'numeric');
            app.Z_finalEditField.ValueChangedFcn = createCallbackFcn(app, @Z_finalEditFieldValueChanged, true);
            app.DesiredEndPositionLabel = uilabel(app.LinearMoveTab);
            app.DesiredEndPositionLabel.Text = 'Desired End Position';
            app.CircularMoveTab = uitab(app.TabGroup2);
            app.CircularMoveTab.Title = 'Circular Move';
            app.X_InterEditFieldLabel = uilabel(app.CircularMoveTab);
            app.X_InterEditFieldLabel.Text = 'X_Inter';
            app.X_InterEditField = uieditfield(app.CircularMoveTab, 'numeric');
            app.X_InterEditField.ValueChangedFcn = createCallbackFcn(app, @X_InterEditFieldValueChanged, true);
            app.Y_InterEditFieldLabel = uilabel(app.CircularMoveTab);
            app.Y_InterEditFieldLabel.Text = 'Y_Inter';
            app.Y_InterEditField = uieditfield(app.CircularMoveTab, 'numeric');
            app.Y_InterEditField.ValueChangedFcn = createCallbackFcn(app, @Y_InterEditFieldValueChanged, true);
            app.Z_genEditFieldLabel = uilabel(app.CircularMoveTab);
            app.Z_genEditFieldLabel.Text = 'Z_gen';
            app.Z_genEditField = uieditfield(app.CircularMoveTab, 'numeric');
            app.Z_genEditField.ValueChangedFcn = createCallbackFcn(app, @Z_genEditFieldValueChanged, true);
            app.X_FinalEditFieldLabel = uilabel(app.CircularMoveTab);
            app.X_FinalEditFieldLabel.Text = 'X_Final';
            app.X_FinalEditField = uieditfield(app.CircularMoveTab, 'numeric');
            app.X_FinalEditField.ValueChangedFcn = createCallbackFcn(app, @X_FinalEditFieldValueChanged, true);
            app.Y_FinalEditFieldLabel = uilabel(app.CircularMoveTab);
            app.Y_FinalEditFieldLabel.Text = 'Y_Final';
            app.Y_FinalEditField = uieditfield(app.CircularMoveTab, 'numeric');
            app.Y_FinalEditField.ValueChangedFcn = createCallbackFcn(app, @Y_FinalEditFieldValueChanged, true);
            app.EnterValuesButton_4 = uibutton(app.CircularMoveTab, 'push');
            app.EnterValuesButton_4.ButtonPushedFcn = createCallbackFcn(app, @EnterValuesButton_4Pushed, true);
            app.EnterValuesButton_4.Text = 'Enter Values';
            app.TeachingModeTab = uitab(app.TabGroup);
            app.TeachingModeTab.Title = 'Teaching Mode';
            app.TeachingModeLabel = uilabel(app.TeachingModeTab);
            app.TeachingModeLabel.Text = 'Teaching Mode';
            app.TeachPositionButton = uibutton(app.TeachingModeTab, 'push');
            app.TeachPositionButton.ButtonPushedFcn = createCallbackFcn(app, @TeachPositionButtonPushed, true);
            app.TeachPositionButton.Text = 'Teach Position';
            app.PlayButton = uibutton(app.TeachingModeTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ResetButton = uibutton(app.TeachingModeTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SendHomeButton = uibutton(app.UIFigure, 'push');
            app.SendHomeButton.ButtonPushedFcn = createCallbackFcn(app, @SendHomeButtonPushed, true);
            app.SendHomeButton.Text = 'Send Home';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%11.5g';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%11.5g';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%11.5g';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EnterValuesButton = uibutton(app.UIFigure, 'push');
            app.EnterValuesButton.ButtonPushedFcn = createCallbackFcn(app, @EnterValuesButtonPushed, true);
            app.EnterValuesButton.Text = 'Enter Values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end